classdef lab4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        GridLayout        matlab.ui.container.GridLayout
        LeftPanel         matlab.ui.container.Panel
        alphaSlider       matlab.ui.control.Slider
        alphaSliderLabel  matlab.ui.control.Label
        modes             matlab.ui.container.ButtonGroup
        Poisson           matlab.ui.control.RadioButton
        alphaCopy         matlab.ui.control.RadioButton
        copy              matlab.ui.control.RadioButton
        Progress          matlab.ui.control.TextArea
        Label_8           matlab.ui.control.Label
        MousePosition     matlab.ui.control.TextArea
        Label_7           matlab.ui.control.Label
        Option_grad       matlab.ui.control.CheckBox
        fore_file         matlab.ui.control.TextArea
        Label_6           matlab.ui.control.Label
        Button_fore       matlab.ui.control.Button
        SaveButton        matlab.ui.control.Button
        back_file         matlab.ui.control.TextArea
        Label_4           matlab.ui.control.Label
        Button_back       matlab.ui.control.Button
        CropButton        matlab.ui.control.Button
        title             matlab.ui.control.Label
        RightPanel        matlab.ui.container.Panel
        Result            matlab.ui.control.UIAxes
        Fore              matlab.ui.control.UIAxes
        Back              matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        background % 背景图片
        foreground % 上层图片
        mask % 掩码
        mixed_grad_mode = 0 % 是否启用混合梯度
        result % 融合结果图片
        mask_croped % 裁剪后的掩码（原图）
        foreground_croped % 裁剪后的上层图片（原图）
        L % 线性方程组的稀疏矩阵不完全LU分解的L
        U % 线性方程组的稀疏矩阵不完全LU分解的U
        P % 线性方程组的稀疏矩阵不完全LU分解的P
        start = 0 % 记录是否开始融合
        mode = 0 % 当前图像融合模式
        alpha = 0.5 % alpha融合的系数
    end

    methods (Access = private)

        % 融合函数
        function result = img_blending(app, background, foreground, mask, offset_y, offset_x)
            % 输入：背景图像、前景图像（剪切后）、掩码（剪切后）、目标区域左上角坐标
            
            % 初始化结果图像
            result = background;
            
            % 确定目标区域
            [fg_height, fg_width, ~] = size(foreground);
            tgt_x1 = offset_x;
            tgt_y1 = offset_y;
            tgt_x2 = offset_x + fg_width - 1;
            tgt_y2 = offset_y + fg_height - 1;
            
            % 对每个颜色通道进行处理
            for c = 1:3
                bg_channel = background(:,:,c);
                fg_channel = foreground(:,:,c);
                bg_tgt = bg_channel(tgt_y1:tgt_y2, tgt_x1:tgt_x2);
                sol_img = zeros(fg_height,fg_width);
                
                switch app.mode
                    case 0
                        % 直接融合
                        for y = 1:fg_height
                            for x = 1:fg_width
                                if mask(y,x)
                                    sol_img(y,x) = fg_channel(y,x);
                                else
                                    sol_img(y,x) = bg_tgt(y,x);
                                end
                            end
                        end

                    case 1
                        % alpha融合
                        for y = 1:fg_height
                            for x = 1:fg_width
                                if mask(y,x)
                                    sol_img(y,x) = app.alpha*fg_channel(y,x)+(1-app.alpha)*bg_tgt(y,x);
                                else
                                    sol_img(y,x) = bg_tgt(y,x);
                                end
                            end
                        end
                        
                    case 2
                        % 泊松融合
                        app.Progress.Value = sprintf("通道%d: 目标梯度计算...",c);
        
                        if app.mixed_grad_mode == 1
                            % 混合梯度法求目标梯度 
                            [grad_x, grad_y] = mixed_gradients(app, fg_channel, bg_tgt);
                        else
                            % 直接求上层梯度
                            [grad_x, grad_y] = gradient(fg_channel);
                        end
        
                        % 创建稀疏矩阵
                        app.Progress.Value = sprintf("通道%d: 稀疏矩阵创建...",c);
                        b = construct_possion_equation_b(app, bg_channel, grad_x, grad_y, mask, tgt_y1, tgt_x1, tgt_y2, tgt_x2);
                        
                        % 解泊松方程
                        app.Progress.Value = sprintf("通道%d: 泊松方程求解...",c);
                        solution = app.U \ (app.L \ (app.P*b));
                        
                        % 将解转换为图像
                        sol_img = reshape(solution, tgt_y2-tgt_y1+1, tgt_x2-tgt_x1+1);
                end
                
                % 将结果放入输出图像
                result(tgt_y1:tgt_y2, tgt_x1:tgt_x2, c) = sol_img;
            end
        end
        
        % 得到线性方程组的稀疏矩阵A
        function A = construct_poisson_equation_A(app, mask, tgt_height, tgt_width)
            % 输入：掩码、目标区域左上、右下角坐标（便于获取原图像素值）

            N = tgt_height * tgt_width; % b的长度
            
            % 初始化稀疏矩阵A行索引向量、列索引向量、值向量和右侧向量b
            A_X = zeros(5*N,1);
            A_Y = zeros(5*N,1);
            A_value = zeros(5*N,1);
            
            % 创建像素索引映射
            indices = reshape(1:N, tgt_height, tgt_width);
            
            count = 1;
            % 遍历目标区域中的每个像素
            for y = 1:tgt_height
                for x = 1:tgt_width
                    idx = indices(y, x);
                    
                    % 如果是mask区域内的像素
                    if mask(y, x)
                        % 计算邻域像素数
                        neighbor = 0;
        
                        % 上下邻域
                        if y > 1
                            neighbor = neighbor - 1;
                            % A(idx, indices(y-1, x)) = 1;
                            A_X(count) = idx;
                            A_Y(count) = indices(y-1, x);
                            A_value(count) = 1;
                            count = count + 1;
                        end
                        if y < tgt_height
                            neighbor = neighbor - 1;
                            % A(idx, indices(y+1, x)) = 1;
                            A_X(count) = idx;
                            A_Y(count) = indices(y+1, x);
                            A_value(count) = 1;
                            count = count + 1;
                        end
                        
                        % 左右邻域
                        if x > 1
                            neighbor = neighbor - 1;
                            % A(idx, indices(y, x-1)) = 1;
                            A_X(count) = idx;
                            A_Y(count) = indices(y, x-1);
                            A_value(count) = 1;
                            count = count + 1;
                        end
                        if x < tgt_width
                            neighbor = neighbor - 1;
                            % A(idx, indices(y, x+1)) = 1;
                            A_X(count) = idx;
                            A_Y(count) = indices(y, x+1);
                            A_value(count) = 1;
                            count = count + 1;
                        end
                        
                        % 设置中心像素
                        % A(idx, idx) = neighbor;
                         A_X(count) = idx;
                         A_Y(count) = idx;
                         A_value(count) = neighbor;
                         count = count + 1;
        
                    else
                        % 非mask区域，保持背景值
                        % A(idx, idx) = 1;
                        A_X(count) = idx;
                        A_Y(count) = idx;
                        A_value(count) = 1;
                        count = count + 1;
                    end

                    app.Progress.Value = sprintf("矩阵构建进度：%.2f",idx/N);

                end
            end
            
            A_res_X = A_X(1:count-1);
            A_res_Y = A_Y(1:count-1);
            A_res_value = A_value(1:count-1);
            A = sparse(A_res_X, A_res_Y, A_res_value, N, N, 5*N);

        end
        
        % 得到线性方程组的右侧向量b
        function b = construct_possion_equation_b(app, bg, grad_x, grad_y, mask, y1, x1, y2, x2)
            % 输入：背景图像、梯度、掩码、目标区域左上、右下角坐标（便于获取原图像素值）
        
            % 获取目标区域大小
            tgt_height = y2 - y1 + 1;
            tgt_width = x2 - x1 + 1;
            N = tgt_height * tgt_width; % A的阶数
            
            % 初始化右侧向量b
            b = zeros(N, 1);
            
            % 创建像素索引映射
            indices = reshape(1:N, tgt_height, tgt_width);

            % 遍历目标区域中的每个像素
            for y = 1:tgt_height
                for x = 1:tgt_width
                    idx = indices(y, x);
                    
                    % 如果是mask区域内的像素
                    if mask(y, x)
                        div = 0;
        
                        % 上下邻域
                        if y == 1
                            div = div + grad_y(y+1, x) - grad_y(y, x);
                        end
                        if y > 1
                            div = div + grad_y(y, x) - grad_y(y-1, x);
                        end
                        
                        % 左右邻域
                        if x == 1
                            div = div + grad_x(y, x+1) - grad_x(y, x);
                        end
                        if x > 1
                            div = div + grad_x(y, x) - grad_x(y, x-1);
                        end
                        
                        % 设置右侧向量
                        b(idx) = div;
        
                    else
                        % 非mask区域，保持背景值
                        b(idx) = bg(y1+y-1, x1+x-1);
                    end
                end
            end
        end

        % 混合梯度法
        function [grad_x, grad_y] = mixed_gradients(app, src, tgt)
        
            % 计算源图像梯度
            [src_gx, src_gy] = gradient(src);
            % 计算目标图像梯度
            [tgt_gx, tgt_gy] = gradient(tgt);
            
            % 选择梯度绝对值较大的那个
            grad_x = src_gx;
            grad_y = src_gy;
            res_x = abs(src_gx) < abs(tgt_gx);
            res_y = abs(src_gy) < abs(tgt_gy);
            grad_x(res_x) = tgt_gx(res_x);
            grad_y(res_y) = tgt_gy(res_y);
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Progress.Value = "请选择背景与上层图像文件";
        end

        % Button pushed function: Button_back
        function Button_backPushed(app, event)
            [filename, pathname] = uigetfile({'*.jpg;*.png;*.bmp','Image Files'}, '选择背景图像');
            if isequal(filename,0)
                disp('用户取消选择');
                return;
            end
            app.background = im2double(imread(fullfile(pathname, filename)));
            imshow(app.background,'Parent',app.Back);
            imshow(app.background,'Parent',app.Result);
            app.back_file.Value = filename;

            if ~(isempty(app.foreground) || isempty(app.background))
                app.Progress.Value = "请裁剪上层图像";
            end
        end

        % Button pushed function: Button_fore
        function Button_forePushed(app, event)
            [filename, pathname] = uigetfile({'*.jpg;*.png;*.bmp','Image Files'}, '选择前景图像');
            if isequal(filename,0)
                disp('用户取消选择');
                return;
            end
            app.foreground = im2double(imread(fullfile(pathname, filename)));
            imshow(app.foreground,'Parent',app.Fore);
            app.fore_file.Value = filename;

            if ~(isempty(app.foreground) || isempty(app.background))
                app.Progress.Value = "请裁剪上层图像";
            end
        end

        % Button pushed function: CropButton
        function CropButtonPushed(app, event)
            
            % 显示前景图像并手动选择区域
            figure, imshow(app.foreground);
            title('请用鼠标绘制多边形选择要融合的区域');
            h = drawpolygon();
            app.mask = createMask(h);
            close;
            
            % 裁剪前景图像
            stats = regionprops('table', app.mask, 'BoundingBox');
            [fg_height, fg_width, ~] = size(app.foreground);
            boundingBox = stats.BoundingBox(1, :); % 提取第一个区域的边界框参数
            
            x_crop = boundingBox(1)-0.5; % 矩形左上角x坐标
            if x_crop > 10 && x_crop + boundingBox(3) + 10 < fg_width
                % 截取区域相对较小时作剪裁
                x_crop = x_crop-10; % 留够边缘宽度方便羽化
                width = boundingBox(3)+20; % 截取区域宽度
            else
                % 截取区域与原前景图片边缘宽度不足时不作剪裁
                x_crop = 1;
                width = fg_width;
            end
        
            y_crop = boundingBox(2)-0.5; % 矩形左上角y坐标
            if y_crop > 10 && y_crop + boundingBox(4) + 10 < fg_height
                y_crop = y_crop-10;
                height = boundingBox(4)+20; % 截取区域高度
            else
                y_crop = 1;
                height = fg_height;
            end
            
            % 剪切
            app.foreground_croped = app.foreground(y_crop:y_crop+height-1,x_crop:x_crop+width-1,:);
            app.mask_croped = app.mask(y_crop:y_crop+height-1,x_crop:x_crop+width-1);
            
            [fg_height, fg_width, ~] = size(app.foreground_croped);
            A = construct_poisson_equation_A(app, app.mask_croped, fg_height, fg_width);
            [app.L, app.U, app.P] = lu(A);

            app.Progress.Value = "请在结果上点击以开始/结束融合过程";
       
        end

        % Callback function: not associated with a component
        function Option_maskValueChanged(app, event)
            value = app.Option_mask.Value;
            app.feather_mask_mode = value;
        end

        % Value changed function: Option_grad
        function Option_gradValueChanged(app, event)
            value = app.Option_grad.Value;
            app.mixed_grad_mode = value;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            [filename, pathname] = uiputfile({'*.png','图像文件'}, '输入图像名');
            if isequal(filename, 0)
                return; % 用户取消选择
            end
            img_path = fullfile(pathname, filename);
            imwrite(app.result,img_path,"png");
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            
            if app.start && ~(isempty(app.mask_croped) || isempty(app.background))
                cp = get(app.Result, 'CurrentPoint');

                % 计算粘贴区域
                % 确保坐标在图像范围内
                [bg_height, bg_width, ~] = size(app.background);
                [fg_height, fg_width, ~] = size(app.foreground_croped);

                x = round(cp(1,1));
                y = round(cp(1,2));
                
                if x >= 0 && y >= 0 && x <= bg_width && y <= bg_height

                    app.MousePosition.Value = sprintf("%.2f, %.2f",x,y);
                    
                    if x - floor(fg_width/2) < 1
                        x_start = 1;
                    else
                        x_start = x - floor(fg_width/2);
                    end
                    if x_start + fg_width - 1 > bg_width
                        x_start = bg_width - fg_width + 1;
                    end

                    if y - floor(fg_height/2) < 1
                        y_start = 1;
                    else
                        y_start = y - floor(fg_height/2);
                    end
                    if y_start + fg_height - 1 > bg_height
                        y_start = bg_height - fg_height + 1;
                    end

                    % 执行融合
                    app.result = img_blending(app, app.background, app.foreground_croped, app.mask_croped, y_start, x_start);

                    app.Progress.Value = "已完成！";
                    
                    % 显示结果
                    imshow(app.result,'Parent',app.Result);
               end
            end
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            if ~(isempty(app.mask_croped) || isempty(app.background))
                cp = get(app.Result, 'CurrentPoint');

                [bg_height, bg_width, ~] = size(app.background);
                x = round(cp(1,1));
                y = round(cp(1,2));
                
                if x >= 0 && y >= 0 && x <= bg_width && y <= bg_height
                    app.start = ~ app.start;
                end
            end
        end

        % Selection changed function: modes
        function modesSelectionChanged(app, event)
            selectedButton = app.modes.SelectedObject;
            switch selectedButton.Text
                case "直接复制"
                    app.mode = 0;
                case "alpha融合"
                    app.mode = 1;
                case "泊松融合"
                    app.mode = 2;
            end
        end

        % Value changed function: alphaSlider
        function alphaSliderValueChanged(app, event)
            value = app.alphaSlider.Value;
            app.alpha = value;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {245, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.title = uilabel(app.LeftPanel);
            app.title.Text = '泊松融合';
            app.CropButton = uibutton(app.LeftPanel, 'push');
            app.CropButton.ButtonPushedFcn = createCallbackFcn(app, @CropButtonPushed, true);
            app.CropButton.Text = '裁剪上层图像';
            app.Button_back = uibutton(app.LeftPanel, 'push');
            app.Button_back.ButtonPushedFcn = createCallbackFcn(app, @Button_backPushed, true);
            app.Button_back.Text = '选择背景文件';
            app.Label_4 = uilabel(app.LeftPanel);
            app.Label_4.Text = '当前背景：';
            app.back_file = uitextarea(app.LeftPanel);
            app.SaveButton = uibutton(app.LeftPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = '保存输出文件';
            app.Button_fore = uibutton(app.LeftPanel, 'push');
            app.Button_fore.ButtonPushedFcn = createCallbackFcn(app, @Button_forePushed, true);
            app.Button_fore.Text = '选择上层文件';
            app.Label_6 = uilabel(app.LeftPanel);
            app.Label_6.Text = '当前上层：';
            app.fore_file = uitextarea(app.LeftPanel);
            app.Option_grad = uicheckbox(app.LeftPanel);
            app.Option_grad.ValueChangedFcn = createCallbackFcn(app, @Option_gradValueChanged, true);
            app.Option_grad.Text = '混合梯度';
            app.Label_7 = uilabel(app.LeftPanel);
            app.Label_7.Text = '鼠标位置';
            app.MousePosition = uitextarea(app.LeftPanel);
            app.Label_8 = uilabel(app.LeftPanel);
            app.Label_8.Text = '进度';
            app.Progress = uitextarea(app.LeftPanel);
            app.modes = uibuttongroup(app.LeftPanel);
            app.modes.SelectionChangedFcn = createCallbackFcn(app, @modesSelectionChanged, true);
            app.modes.Title = '模式';
            app.copy = uiradiobutton(app.modes);
            app.copy.Text = '直接复制';
            app.copy.Value = true;
            app.alphaCopy = uiradiobutton(app.modes);
            app.alphaCopy.Text = 'alpha融合';
            app.Poisson = uiradiobutton(app.modes);
            app.Poisson.Text = '泊松融合';
            app.alphaSliderLabel = uilabel(app.LeftPanel);
            app.alphaSliderLabel.Text = 'alpha';
            app.alphaSlider = uislider(app.LeftPanel);
            app.alphaSlider.Limits = [0 1];
            app.alphaSlider.ValueChangedFcn = createCallbackFcn(app, @alphaSliderValueChanged, true);
            app.alphaSlider.Value = 0.5;
            app.RightPanel = uipanel(app.GridLayout);
            app.Back = uiaxes(app.RightPanel);
            title(app.Back, '背景图像')
            app.Back.XTick = [];
            app.Back.YTick = [];
            app.Fore = uiaxes(app.RightPanel);
            title(app.Fore, '上层图像')
            app.Fore.XTick = [];
            app.Fore.YTick = [];
            app.Result = uiaxes(app.RightPanel);
            title(app.Result, '融合结果')
            app.Result.XTick = [];
            app.Result.XTickLabel = '';
            app.Result.YTick = [];
            app.Result.YTickLabel = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lab4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end